classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ImportButton                   matlab.ui.control.Button
        ChoosingreadingEditFieldLabel  matlab.ui.control.Label
        ChoosingreadingEditField       matlab.ui.control.NumericEditField
        ObjectDropDownLabel            matlab.ui.control.Label
        ObjectDropDown                 matlab.ui.control.DropDown
        GabortransformButton           matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        ExportButton                   matlab.ui.control.Button
        ReadingofAmustbeinrange1315Label  matlab.ui.control.Label
        ReadingofBmustbeinrange1200Label  matlab.ui.control.Label
        PredictButton                  matlab.ui.control.Button
        ObjectALampLabel               matlab.ui.control.Label
        ObjectALamp                    matlab.ui.control.Lamp
        ObjectBLampLabel               matlab.ui.control.Label
        ObjectBLamp                    matlab.ui.control.Lamp
        ImportnewdataxlsxButton        matlab.ui.control.Button
        GabortransformnewdataButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            load A.mat;
            t = (1:width(A));
            S = table2array(B(app.ChoosingreadingEditField.Value,1:width(B)));
            if app.ObjectDropDown.Value == 'A'
                S = table2array(A(app.ChoosingreadingEditField.Value,1:width(A)));
            end           
            S = S./max(max(S),abs(min(S)));
            plot(app.UIAxes,t,S);
            xlim(app.UIAxes,[1 width(A)]);
            app.ObjectALamp.Visible = 'off';
            app.ObjectBLamp.Visible = 'off';
        end

        % Value changed function: ChoosingreadingEditField
        function ChoosingreadingEditFieldValueChanged(app, event)
            value = app.ChoosingreadingEditField.Value;
        end

        % Value changed function: ObjectDropDown
        function ObjectDropDownValueChanged(app, event)
            value = app.ObjectDropDown.Value;
        end

        % Button pushed function: GabortransformButton
        function GabortransformButtonPushed(app, event)
            load A.mat;
            t = (1:width(A));
            S = table2array(B(app.ChoosingreadingEditField.Value,1:width(B)));
            if app.ObjectDropDown.Value == 'A'
                S = table2array(A(app.ChoosingreadingEditField.Value,1:width(A)));
            end           
            S = S./max(max(S),abs(min(S)));

            L = 10; %Signal duration = 10s assumption
            n = 3400; % Number of data points
            k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k);
            Sgt_spec = [];
            tslide = 0:20:3400; % Moving Gabor filter every 20 samples !
            for j = 1:length(tslide)
                g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
                Sg = g.*S;
                Sgt = fft(Sg);
                Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
            end
            Sgt_spec=Sgt_spec';
            app.UIAxes2.Visible = 'on';
            pcolor(app.UIAxes2,tslide,ks,Sgt_spec);
            shading(app.UIAxes2,"interp");    
            colormap(app.UIAxes2,"hot");
            xlim(app.UIAxes2,[1 width(A)]);
            ylim(app.UIAxes2,[30 60]);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.UIAxes2.Visible = 'off';
            colormap(app.UIAxes2,"gray");
            exportgraphics(app.UIAxes2,'spectrogram.jpg',"Resolution",100);
            image = imread("spectrogram.jpg");
            image = imresize(image,[361 456]);
            imwrite(image,"spectrogram.jpg");
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            app.ObjectALamp.Visible = 'off';
            app.ObjectBLamp.Visible = 'off';
            load net.mat           
            imds = imageDatastore('spectrogram.jpg');
            prediction = classify(net,imds);
            
            if prediction == "spectrogramA"
                app.ObjectALamp.Visible = 'on';
                app.ObjectALamp.Color = 'red';
            end
            if prediction == "spectrogramB"
                app.ObjectBLamp.Visible = 'on';
                app.ObjectBLamp.Color = 'red';
            end           
        end

        % Button pushed function: ImportnewdataxlsxButton
        function ImportnewdataxlsxButtonPushed(app, event)
            C = readtable('newdata.xlsx');
            t = (1:width(C));
            S = table2array(C(1,1:width(C)));           
            S = S./max(max(S),abs(min(S)));
            
            plot(app.UIAxes,t,S);
            xlim(app.UIAxes,[1 width(C)]);
            app.ObjectALamp.Visible = 'off';
            app.ObjectBLamp.Visible = 'off';
        end

        % Button pushed function: GabortransformnewdataButton
        function GabortransformnewdataButtonPushed(app, event)
           C = readtable('newdata.xlsx');
           t = (1:width(C));
           S = table2array(C(1,1:width(C)));
           S = S./max(max(S),abs(min(S)));
                     
           L = 10; %Signal duration = 10s assumption
           n = 3400; % Number of data points
           k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k);
           Sgt_spec = [];
           tslide = 0:20:3400; % Moving Gabor filter every 20 samples !
           for j = 1:length(tslide)
               g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
               Sg = g.*S;
               Sgt = fft(Sg);
               Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
           end
           Sgt_spec=Sgt_spec';
           app.UIAxes2.Visible = 'on';
           pcolor(app.UIAxes2,tslide,ks,Sgt_spec);
           shading(app.UIAxes2,"interp");
           colormap(app.UIAxes2,"hot");
           xlim(app.UIAxes2,[1 width(C)]);
           ylim(app.UIAxes2,[30 60]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reflected signal')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'magnitude')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ChoosingreadingEditFieldLabel = uilabel(app.UIFigure);
            app.ChoosingreadingEditFieldLabel.Text = 'Choosing reading #';
            app.ChoosingreadingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChoosingreadingEditField.ValueChangedFcn = createCallbackFcn(app, @ChoosingreadingEditFieldValueChanged, true);
            app.ObjectDropDownLabel = uilabel(app.UIFigure);
            app.ObjectDropDownLabel.Text = 'Object';
            app.ObjectDropDown = uidropdown(app.UIFigure);
            app.ObjectDropDown.Items = {'A', 'B'};
            app.ObjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectDropDownValueChanged, true);
            app.ObjectDropDown.Value = 'A';
            app.GabortransformButton = uibutton(app.UIFigure, 'push');
            app.GabortransformButton.ButtonPushedFcn = createCallbackFcn(app, @GabortransformButtonPushed, true);
            app.GabortransformButton.Text = {'Gabor transform'; ''};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'The respective spectrogram')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'frequency')
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ReadingofAmustbeinrange1315Label = uilabel(app.UIFigure);
            app.ReadingofAmustbeinrange1315Label.Text = 'Reading # of A must be in range [1, 315]';
            app.ReadingofBmustbeinrange1200Label = uilabel(app.UIFigure);
            app.ReadingofBmustbeinrange1200Label.Text = 'Reading # of B must be in range [1, 200]';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ObjectALampLabel = uilabel(app.UIFigure);
            app.ObjectALampLabel.Text = 'Object A';
            app.ObjectALamp = uilamp(app.UIFigure);
            app.ObjectBLampLabel = uilabel(app.UIFigure);
            app.ObjectBLampLabel.Text = 'Object B';
            app.ObjectBLamp = uilamp(app.UIFigure);
            app.ImportnewdataxlsxButton = uibutton(app.UIFigure, 'push');
            app.ImportnewdataxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @ImportnewdataxlsxButtonPushed, true);
            app.ImportnewdataxlsxButton.Text = 'Import newdata.xlsx';
            app.GabortransformnewdataButton = uibutton(app.UIFigure, 'push');
            app.GabortransformnewdataButton.ButtonPushedFcn = createCallbackFcn(app, @GabortransformnewdataButtonPushed, true);
            app.GabortransformnewdataButton.Text = 'Gabor transform new data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end