classdef GUIapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrainingSamplesSliderLabel      matlab.ui.control.Label
        TrainingSamplesSlider           matlab.ui.control.Slider
        TrainButton                     matlab.ui.control.Button
        DatafiletotestEditFieldLabel    matlab.ui.control.Label
        DatafiletotestEditField         matlab.ui.control.EditField
        TrainFileNameEditFieldLabel     matlab.ui.control.Label
        TrainFileNameEditField          matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        LoadTestDataButton              matlab.ui.control.Button
        ObjectLabelEditFieldLabel       matlab.ui.control.Label
        ObjectLabelEditField            matlab.ui.control.EditField
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button2                         matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        NameofTestEditFieldLabel        matlab.ui.control.Label
        NameofTestEditField             matlab.ui.control.EditField
        Button2_2                       matlab.ui.control.Button
        NameofExistingTestEditFieldLabel  matlab.ui.control.Label
        NameofExistingTestEditField     matlab.ui.control.EditField
        LoadtrainednetworkButton        matlab.ui.control.Button
        CurrentTestEditFieldLabel       matlab.ui.control.Label
        CurrentTestEditField            matlab.ui.control.EditField
        LabelofValidationDataEditFieldLabel  matlab.ui.control.Label
        LabelofValidationDataEditField  matlab.ui.control.EditField
        PredictasEditFieldLabel         matlab.ui.control.Label
        PredictasEditField              matlab.ui.control.EditField
        PredictButton                   matlab.ui.control.Button
        OutputFolderNameEditFieldLabel  matlab.ui.control.Label
        OutputFolderNameEditField       matlab.ui.control.EditField
        DatafiletovalidateLabel         matlab.ui.control.Label
        DatafiletovalidateEditField     matlab.ui.control.EditField
        Button_6                        matlab.ui.control.Button
        ValidateButton                  matlab.ui.control.Button
        LoadvalidationdataButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        layers, options;                    %network properties 
        net;                                %used network
        imds, imdsTrain, imdsValidation, imdsPredict;    %image data objects
        nameOfCurrentTest;
        testImagecurrentIndex = 1;
        YPred;
        currentFolder;
    end
    
    methods (Access = private)
%==============================================================================================================================================           
        function updateDataset(app)
            app.currentFolder = pwd;
            app.imds = imageDatastore(fullfile(app.currentFolder, 'trainingData'),...
            'IncludeSubfolders',true,'LabelSource','foldernames');
            %% Getting number of labels
            filesToTrain = int8(app.TrainingSamplesSlider.Value);
            [app.imdsTrain,app.imdsValidation] = splitEachLabel(app.imds,filesToTrain,'randomize');
        end
%==============================================================================================================================================        
        function networkInit(app)
            % Initialize the network with fixed value before training
            app.layers = [
                imageInputLayer(size(readimage(app.imds,1)))
                
                convolution2dLayer(64,8,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(32,'Stride',8)
                
                convolution2dLayer(32,16,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(16,'Stride',16)
                
                convolution2dLayer(16,32,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                fullyConnectedLayer(2)
                softmaxLayer
                classificationLayer];
            
            %% Specify Training Options for the model
            app.options = trainingOptions('sgdm', ...
                'InitialLearnRate',0.01, ...
                'MaxEpochs',5, ...
                'Shuffle','every-epoch', ...
                'ValidationData',app.imdsValidation, ...
                'ValidationFrequency',30, ...
                'Verbose',false, ...
                'Plots','training-progress', ...
                'MiniBatchSize',2, ...
                'ExecutionEnvironment','gpu');
        end
%==============================================================================================================================================           
        function updateOutput(app)
            %Update the output display image in predicting mode
            app.PredictasEditField.Value = string(app.YPred(app.testImagecurrentIndex,1));
            a = app.currentFolder+ "/testingData/test_"+ string(app.testImagecurrentIndex) +".jpg";
            app.Image.ImageSource = a;
        end
%==============================================================================================================================================   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            if(app.NameofTestEditField.Value=="")
                errordlg("Please insert the test name to save the network","lack test Name")
                return;
            end
            updateDataset(app);
            networkInit(app);
            app.net = trainNetwork(app.imdsTrain,app.layers,app.options);
            app.nameOfCurrentTest=app.NameofTestEditField.Value;
            app.CurrentTestEditField.Value = app.nameOfCurrentTest;
            net = app.net;
            save([app.nameOfCurrentTest,'.mat'],'net');
        end

        % Button pushed function: LoadButton
        function LoadTrainDataButton_2Pushed(app, event)
            
            %-- Retrieve object label from input field
            dataLabel = app.ObjectLabelEditField.Value;
            if(dataLabel == "")
                errordlg("dataLabel not inserted");
                return;
            end
            %-- Retrieve training data file name in the same directory
            dataFile = app.TrainFileNameEditField.Value;
            if(dataFile == "")
                errordlg("File of Data is not inserted");
                return;
            end
            msgbox("reading Data from xlsx file","modal");
            B = readtable(dataFile+".xlsx");
            msgbox("Performing Gabor Transformation to read data to pictures","modal");
            t = (1:width(B)); % Time vector 1:3400 samples
            mkdir("trainingData/"+dataLabel);
            for c = 1:200
                close all
                S = table2array(B(c,1:width(B))); 
                S = S./max(max(S),abs(min(S))); % normalizing
                L = 10; %Signal duration = 10s assumption
                n = 3400; % Number of samples
                k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k); 
                Sgt_spec = [];
                tslide = 0:20:3400; % Moving Gabor filter every 20 samples !
                for j = 1:length(tslide)
                    g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
                    Sg = g.*S;
                    Sgt = fft(Sg);
                    Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
                end
                Sgt_spec=Sgt_spec';% transpose the Spectrogram for better representation
                figure('Name',"Spectrogram of reading "+c+" of Object B");
                pcolor(tslide,ks,Sgt_spec);
                shading(gca, 'interp');
                set(gca,'Ylim',[30 60]);
                colormap(gca,'gray');
                set(gca, 'Visible', 'off');
                exportgraphics(gca,"trainingData/"+dataLabel+"/"+dataLabel+"_"+c+".jpg",'Resolution',100)
            end
            msgbox("Pictures of data created in trainingData/")
        end

        % Button pushed function: LoadTestDataButton
        function LoadTestDataButtonPushed(app, event)
            mkdir("testingData");
            app.currentFolder = pwd;
            app.testImagecurrentIndex = 1;
            %========================================================================================================================
            dataFile = app.DatafiletotestEditField.Value;
            if(dataFile == "")
                errordlg("File of Data is not inserted");
                return;
            end
            %========================================================================================================================
            msgbox("reading Data from xlsx file","modal");
            B = readtable(dataFile+".xlsx");
            msgbox("Performing Gabor Transformation to read data to pictures","modal");
            t = (1:width(B)); % Time vector 1:3400 samples
            for c = 1:height(B)
                close all
                S = table2array(B(c,1:width(B))); 
                S = S./max(max(S),abs(min(S))); % normalizing
                L = 10; %Signal duration = 10s assumption
                n = 3400; % Number of samples
                k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k); 
                Sgt_spec = [];
                tslide = 0:20:3400; % Moving Gabor filter every 20 samples !
                for j = 1:length(tslide)
                    g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
                    Sg = g.*S;
                    Sgt = fft(Sg);
                    Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
                end
                Sgt_spec=Sgt_spec';% transpose the Spectrogram for better representation
                pcolor(tslide,ks,Sgt_spec);
                shading(gca, 'interp');
                set(gca,'Ylim',[30 60]);
                colormap(gca,'gray');
                set(gca, 'Visible', 'off');
                exportgraphics(gca,"testingData/test_"+c+".jpg",'Resolution',150)
                pause(0.01);
            end
            msgbox("Pictures of data created in testingData/");
        end

        % Button pushed function: LoadtrainednetworkButton
        function LoadtrainednetworkButtonPushed(app, event)
            try
                app.currentFolder = pwd;
                %loading the saved network
                load(app.NameofExistingTestEditField.Value,"-mat",'net');
                app.net = net;
                disp(app.net)
                app.nameOfCurrentTest = app.NameofExistingTestEditField.Value;
                app.CurrentTestEditField.Value = app.nameOfCurrentTest;
                if(isempty(app.net))
                    errordlg('test network could not be loaded');
                    return;
                end
                msgbox("Test is successfully loaded");
            catch
                errordlg("Test cannot be found/n","Load failed","modal");
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            app.imdsPredict = imageDatastore(fullfile(app.currentFolder, 'testingData'));
            disp(app.imdsPredict);
            if(isempty(app.net))
                errordlg("network not loaded or no trained network found");
                return;
            end
            if(isempty(app.imdsPredict))
                errordlg("Data to predict not loaded into the program");
                return;
            end
            try
            app.YPred = classify(app.net,app.imdsPredict);
            catch
                msgbox("cannot execute predict due to difference between network and image resolution");
                return;
            end
            updateOutput(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if(app.testImagecurrentIndex==height(app.YPred))
                msgbox("end of testing file","modal");
                return
            end
            app.testImagecurrentIndex = app.testImagecurrentIndex+1;
            updateOutput(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if(app.testImagecurrentIndex==1)
                msgbox("this is the first file in the testing set","modal");
                return
            end
            app.testImagecurrentIndex = app.testImagecurrentIndex-1;
            updateOutput(app);
        end

        % Button pushed function: LoadvalidationdataButton
        function LoadvalidationdataButtonPushed(app, event)
            mkdir("validationData");
            app.currentFolder = pwd;
            %========================================================================================================================
            dataFile = app.DatafiletovalidateEditField.Value;
            if(dataFile == "")
                errordlg("File of Data is not inserted");
                return;
            end
            dataLabel = app.LabelofValidationDataEditField.Value;
            if(dataLabel == "")
                errordlg("Label of Data is not inserted");
                return;
            end
            %========================================================================================================================
            mkdir("validationData/"+dataLabel);
            msgbox("reading Data from xlsx file","modal");
            try
                B = readtable(dataFile+".xlsx");
            catch
                msgbox(" program cannot find the specified file","modal");
                return;
            end
            msgbox("Performing Gabor Transformation to read data to pictures","modal");
            t = (1:width(B)); % Time vector 1:3400 samples
            for c = 1:height(B)
                close all
                S = table2array(B(c,1:width(B))); 
                S = S./max(max(S),abs(min(S))); % normalizing
                L = 10; %Signal duration = 10s assumption
                n = 3400; % Number of samples
                k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k); 
                Sgt_spec = [];
                tslide = 0:20:3400; % Moving Gabor filter every 20 samples !
                for j = 1:length(tslide)
                    g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
                    Sg = g.*S;
                    Sgt = fft(Sg);
                    Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
                end
                Sgt_spec=Sgt_spec';% transpose the Spectrogram for better representation
                pcolor(tslide,ks,Sgt_spec);
                shading(gca, 'interp');
                set(gca,'Ylim',[30 60]);
                colormap(gca,'gray');
                set(gca, 'Visible', 'off');
                exportgraphics(gca,"validationData/"+dataLabel+"/test_"+c+".jpg",'Resolution',150)
                pause(0.01);
            end
            msgbox("Pictures of data created in validationData/");
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            app.imdsValidation = imageDatastore(fullfile(app.currentFolder, 'validationData'),...
            'IncludeSubfolders',true,'LabelSource','foldernames');
            YPred = classify(app.net,app.imdsValidation);
            YValidation = app.imdsValidation.Labels;
            accuracy = sum(YPred == YValidation)/numel(YValidation);
            plotconfusion(YPred,YValidation)
            
            TP = 0;
            TN = 0;
            FP = 0;
            FN = 0;
            
            for i=1:size(YPred)
                if (YValidation(i)=="spectrogramA")&&(YPred(i)=="spectrogramA")
                    TP = TP+1;
                end
                if (YValidation(i)=="spectrogramB")&&(YPred(i)=="spectrogramB")
                    TN = TN+1;
                end
                if (YValidation(i)=="spectrogramB")&&(YPred(i)=="spectrogramA")
                    FP = FP+1;
                end
                if (YValidation(i)=="spectrogramA")&&(YPred(i)=="spectrogramB")
                    FN = FN+1;
                end
            end
            PPV = TP/(FP+TP);
            FDR = FP/(FP+TP);
            NPV = TN/(TN+FN);
            FOR = FN/(TN+FN);
            TPR = TP/(TP+FN);
            TNR = TN/(TN+FP);
            F1 = 2*TP/(2*TP+FP+FN);
            
            FPR = FP/(FP+TN);
            
            FPRm = [0 FPR 1];
            TPRm = [0 TPR 1];
            figure
            plot(FPRm, TPRm)
            grid
            axis([0 1 0 1]) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrainingSamplesSliderLabel = uilabel(app.UIFigure);
            app.TrainingSamplesSliderLabel.Text = 'Training Samples';
            app.TrainingSamplesSlider = uislider(app.UIFigure);
            app.TrainingSamplesSlider.Limits = [0 150];
            app.TrainingSamplesSlider.Tooltip = {'Specify the number of picture in every dataset folder used for training'};
            app.TrainingSamplesSlider.Value = 1;
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Tooltip = {'Train a net to classify the specified classes in TrainingData'};
            app.TrainButton.Text = 'Train';
            app.DatafiletotestEditFieldLabel = uilabel(app.UIFigure);
            app.DatafiletotestEditFieldLabel.Text = 'Data file to test';
            app.DatafiletotestEditField = uieditfield(app.UIFigure, 'text');
            app.DatafiletotestEditField.Tooltip = {'Isert the name of the test xlsx file without the file type e.g. combinedataforA'};
            app.TrainFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.TrainFileNameEditFieldLabel.Text = 'Train File Name';
            app.TrainFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.TrainFileNameEditField.Tooltip = {'Isert the name of the xlsx file without the file type e.g. combinedataforA'};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainDataButton_2Pushed, true);
            app.LoadButton.Tooltip = {'Load Data from xlxs dataset to spectrogram with Gabor transformation'};
            app.LoadButton.Text = 'Load';
            app.LoadTestDataButton = uibutton(app.UIFigure, 'push');
            app.LoadTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestDataButtonPushed, true);
            app.LoadTestDataButton.Tooltip = {'Load Data from xlxs dataset to spectrogram with Gabor transformation'};
            app.LoadTestDataButton.Text = 'Load Test Data';
            app.ObjectLabelEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectLabelEditFieldLabel.Text = 'Object Label';
            app.ObjectLabelEditField = uieditfield(app.UIFigure, 'text');
            app.ObjectLabelEditField.Tooltip = {'Type in the label of the inserted data in the file name field e.g "LabelA"'};
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Button2';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.NameofTestEditFieldLabel = uilabel(app.UIFigure);
            app.NameofTestEditFieldLabel.Text = 'Name of Test';
            app.NameofTestEditField = uieditfield(app.UIFigure, 'text');
            app.NameofTestEditField.Tooltip = {'Name of Test - name of the networks to be saved in a .mat file'};
            app.Button2_2 = uibutton(app.UIFigure, 'push');
            app.Button2_2.Text = 'Button2';
            app.NameofExistingTestEditFieldLabel = uilabel(app.UIFigure);
            app.NameofExistingTestEditFieldLabel.Text = 'Name of Existing Test';
            app.NameofExistingTestEditField = uieditfield(app.UIFigure, 'text');
            app.LoadtrainednetworkButton = uibutton(app.UIFigure, 'push');
            app.LoadtrainednetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtrainednetworkButtonPushed, true);
            app.LoadtrainednetworkButton.Text = 'Load trained network';
            app.CurrentTestEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentTestEditFieldLabel.Text = 'Current Test ';
            app.CurrentTestEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentTestEditField.Editable = 'off';
            app.LabelofValidationDataEditFieldLabel = uilabel(app.UIFigure);
            app.LabelofValidationDataEditFieldLabel.Text = 'Label of Validation Data';
            app.LabelofValidationDataEditField = uieditfield(app.UIFigure, 'text');
            app.PredictasEditFieldLabel = uilabel(app.UIFigure);
            app.PredictasEditFieldLabel.Text = 'Predict as:';
            app.PredictasEditField = uieditfield(app.UIFigure, 'text');
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.OutputFolderNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFolderNameEditFieldLabel.Text = 'Output Folder Name';
            app.OutputFolderNameEditField = uieditfield(app.UIFigure, 'text');
            app.DatafiletovalidateLabel = uilabel(app.UIFigure);
            app.DatafiletovalidateLabel.Text = 'Data file to validate';
            app.DatafiletovalidateEditField = uieditfield(app.UIFigure, 'text');
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '';
            app.ValidateButton = uibutton(app.UIFigure, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.LoadvalidationdataButton = uibutton(app.UIFigure, 'push');
            app.LoadvalidationdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvalidationdataButtonPushed, true);
            app.LoadvalidationdataButton.Text = 'Load validation data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end