classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        TrainingSamplesSliderLabel    matlab.ui.control.Label
        TrainingSamplesSlider         matlab.ui.control.Slider
        TrainButton                   matlab.ui.control.Button
        DatafiletotestEditFieldLabel  matlab.ui.control.Label
        DatafiletotestEditField       matlab.ui.control.EditField
        TrainFileNameEditFieldLabel   matlab.ui.control.Label
        TrainFileNameEditField        matlab.ui.control.EditField
        LoadButton                    matlab.ui.control.Button
        LoadTestDataButton            matlab.ui.control.Button
        ObjectLabelEditFieldLabel     matlab.ui.control.Label
        ObjectLabelEditField          matlab.ui.control.EditField
        PreviousButton                matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        OutputLabel                   matlab.ui.control.Label
        Button_2                      matlab.ui.control.Button
        Button2                       matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        NotyetLabel                   matlab.ui.control.Label
        NotyetLabel_2                 matlab.ui.control.Label
        TestingLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        layers, options;                    %network properties 
        net;                                %stored network
        imds, imdsTrain, imdsValidation;    %image data objects
    end
    
    methods (Access = private)
        
        function training(app)
            %% Train The CNN Network
            if(exist(app.imdsTrain,"class") ~= 1)
                errordlg('Training Datas not initialized','imdsTrain not defined',"modal");
                %return;
            end
            if((exist(app.layers,"class") ~= 1) || (exist(app.options,"class") ~= 1))
                errordlg('Training Datas not initialized','imdsTrain not defined',"modal");
                return;
            end
            app.net = trainNetwork(app.imdsTrain,app.layers,app.options);
        end
        
        function updateDataset(app)
            app.imds = imageDatastore(fullfile(currentFolder, 'trainingData'),...
            'IncludeSubfolders',true,'LabelSource','foldernames');
            %% Getting number of labels
            filesToTrain = 150;
            [app.imdsTrain,app.imdsValidation] = splitEachLabel(app.imds,filesToTrain,'randomize');
        end
        
        function netInit(app)
            %% Declare the CNN network 
            app.layers = [
                imageInputLayer(size(readimage(app.imds,1)))
                
                convolution2dLayer(64,8,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(32,'Stride',8)
                
                convolution2dLayer(32,16,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(16,'Stride',16)
                
                convolution2dLayer(16,32,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                fullyConnectedLayer(2)
                softmaxLayer
                classificationLayer];
            
            %% Specify Training Options for the model
            app.options = trainingOptions('sgdm', ...
                'InitialLearnRate',0.01, ...
                'MaxEpochs',5, ...
                'Shuffle','every-epoch', ...
                'ValidationData',app.imdsValidation, ...
                'ValidationFrequency',30, ...
                'Verbose',false, ...
                'Plots','training-progress', ...
                'MiniBatchSize',2, ...
                'ExecutionEnvironment','gpu');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            training(app)
        end

        % Callback function: LoadButton, UIAxes
        function LoadTrainDataButton_2Pushed(app, event)
            dataLabel = app.ObjectLabelEditField.Value;
            if(dataLabel == "")
                errordlg("dataLabel not inserted");
                return;
            end
            dataFile = app.TrainFileNameEditField.Value;
            if(dataFile == "")
                errordlg("File of Data is not inserted");
                return;
            end
            msgbox("reading Data from xlsx file","modal");
            B = readtable(dataFile+".xlsx");
            msgbox("Performing Gabor Transformation to read data to pictures","modal");
            t = (1:width(B)); % Time vector 1:3400 samples
            for c = 1:20
                close all
                S = table2array(B(c,1:width(B))); 
                S = S./max(max(S),abs(min(S))); % normalizing
                L = 10; %Signal duration = 10s assumption
                n = 3400; % Number of samples
                k = (2*pi/L)*[0:n/2-1 -n/2:-1]; ks = fftshift(k); 
                Sgt_spec = [];
                tslide = 0:20:3400; % Moving Gabor filter every 20 samples !
                for j = 1:length(tslide)
                    g = exp(-5*1e-5*(t-tslide(j)).^2); %Gabor filter function
                    Sg = g.*S;
                    Sgt = fft(Sg);
                    Sgt_spec=[Sgt_spec; abs(fftshift(Sgt))];
                end
                Sgt_spec=Sgt_spec';% transpose the Spectrogram for better representation
                pcolor(app.UIAxes,tslide,ks,Sgt_spec);
                shading(app.UIAxes, 'interp');
                set(app.UIAxes,'Ylim',[30 60]);
                colormap(app.UIAxes,'gray');
                set(app.UIAxes, 'Visible', 'off');
                mkdir("trainingData/"+dataLabel);
                exportgraphics(app.UIAxes,"trainingData/"+dataLabel+"/"+dataLabel+"_"+c+".jpg",'Resolution',100)
            end
            msgbox("Pictures of data created in trainingData/")
        end

        % Button pushed function: LoadTestDataButton
        function LoadTestDataButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Training Image display')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Test Picture')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TrainingSamplesSliderLabel = uilabel(app.UIFigure);
            app.TrainingSamplesSliderLabel.Text = 'Training Samples';
            app.TrainingSamplesSlider = uislider(app.UIFigure);
            app.TrainingSamplesSlider.Limits = [0 150];
            app.TrainingSamplesSlider.Value = 1;
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.DatafiletotestEditFieldLabel = uilabel(app.UIFigure);
            app.DatafiletotestEditFieldLabel.Text = 'Data file to test';
            app.DatafiletotestEditField = uieditfield(app.UIFigure, 'text');
            app.TrainFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.TrainFileNameEditFieldLabel.Text = 'Train File Name';
            app.TrainFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.TrainFileNameEditField.Tooltip = {'Isert the name of the xlsx file without the file type e.g. combinedataforA'};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainDataButton_2Pushed, true);
            app.LoadButton.Tooltip = {'Load Data from xlxs dataset to spectrogram with Gabor transformation'};
            app.LoadButton.Text = 'Load';
            app.LoadTestDataButton = uibutton(app.UIFigure, 'push');
            app.LoadTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestDataButtonPushed, true);
            app.LoadTestDataButton.Tooltip = {'Load Data from xlxs dataset to spectrogram with Gabor transformation'};
            app.LoadTestDataButton.Text = 'Load Test Data';
            app.ObjectLabelEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectLabelEditFieldLabel.Text = 'Object Label';
            app.ObjectLabelEditField = uieditfield(app.UIFigure, 'text');
            app.ObjectLabelEditField.Tooltip = {'Type in the label of the inserted data in the file name field e.g "LabelA"'};
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Button2';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.NotyetLabel = uilabel(app.UIFigure);
            app.NotyetLabel.Text = 'Not yet';
            app.NotyetLabel_2 = uilabel(app.UIFigure);
            app.NotyetLabel_2.Text = 'Not yet';
            app.TestingLabel = uilabel(app.UIFigure);
            app.TestingLabel.Text = 'Testing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end