classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TrainingSamplesSliderLabel  matlab.ui.control.Label
        TrainingSamplesSlider       matlab.ui.control.Slider
        TrainButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            %% Specify the dataset directory
            digitDatasetPath = pwd;
            imds = imageDatastore(digitDatasetPath, ...
                'IncludeSubfolders',true,'LabelSource','foldernames');
            %% Getting number of labels
            %% Getting Image Size from first image in 'imds'
            img = readimage(imds,1);
            %% Specify the number of files used for training
            filesToTrain = int8(app.TrainingSamplesSlider.Value);
            [imdsTrain,imdsValidation] = splitEachLabel(imds,filesToTrain,'randomize');
            %% Declare the CNN network 
            layers = [
                imageInputLayer(size(img))
                
                convolution2dLayer(64,8,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(32,'Stride',8)
                
                convolution2dLayer(32,16,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(16,'Stride',16)
                
                convolution2dLayer(16,32,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                fullyConnectedLayer(2)
                softmaxLayer
                classificationLayer];
            
            %% Specify Training Options for the model
            options = trainingOptions('sgdm', ...
                'InitialLearnRate',0.01, ...
                'MaxEpochs',2, ...
                'Shuffle','every-epoch', ...
                'ValidationData',imdsValidation, ...
                'ValidationFrequency',30, ...
                'Verbose',false, ...
                'Plots','training-progress', ...
                'MiniBatchSize',4, ...
                'ExecutionEnvironment','gpu');
            %% Train The CNN Network
            net = trainNetwork(imdsTrain,layers,options);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrainingSamplesSliderLabel = uilabel(app.UIFigure);
            app.TrainingSamplesSliderLabel.Text = 'Training Samples';
            app.TrainingSamplesSlider = uislider(app.UIFigure);
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end